classdef BreathRate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UIAxes       matlab.ui.control.UIAxes
        Button       matlab.ui.control.Button
        LabelSwitch  matlab.ui.control.Label
        Switch       matlab.ui.control.Switch
        UIAxes2      matlab.ui.control.UIAxes
        UIAxes3      matlab.ui.control.UIAxes
        Label        matlab.ui.control.Label
        Label_2      matlab.ui.control.Label
        Label_3      matlab.ui.control.Label
    end

    
    properties (Access = private)
        breathRecorder = audiorecorder(44100,16,1);
        audioSignal;
        Fs;
        audioOut;
        time;

    end

    methods (Access = private)
    
        function results = BreathingRate(app)
            
            hold(app.UIAxes3,'on');
            SPL = splMeter('SampleRate',44100);
            [Lt,Leq,Lpeak,Lmax] = SPL(app.audioOut);
            Lt=smoothdata(Lt);
            [pek,locs]=findpeaks(Lt,app.time);
            disp(length(pek));
            plot(app.UIAxes3,app.time,Lt,locs,pek,'o');
            app.Label.Text=string((length(pek)*60)/(length(app.audioSignal)/app.Fs))+' B/M '+length(pek);
            
            
            StandardDeviation=std(Lt);
            StandardDeviation=Lt(:)-StandardDeviation;
            StandardDeviation=smoothdata(StandardDeviation);
            
            [pek,locs]=findpeaks(StandardDeviation,app.time);
            plot(app.UIAxes3,app.time,StandardDeviation,locs,pek,'*');
            app.Label_2.Text=string((length(pek)*60)/(length(app.audioSignal)/app.Fs))+'B/M '+length(pek);
         
             MeanSignal=mean(Lt);
            MeanSignal=Lt(:)-MeanSignal;
            MeanSignal=smoothdata(MeanSignal);
            [pek,locs]=findpeaks(MeanSignal,app.time);
            plot(app.UIAxes3,app.time,MeanSignal,locs,pek,'*    ');
            app.Label_3.Text=string((length(pek)*60)/(length(app.audioSignal)/app.Fs))+'B/M '+length(pek);
            legend(app.UIAxes3,'Time-weighted sound level in dB','peaks','Time-weighted sound level in dB-StandardDeviation','peaks','Time-weighted sound level in dB-Mean','peaks');
            hold(app.UIAxes3,'off');
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: Switch
        function RecordSwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value
                case 'Off'
                    stop(app.breathRecorder);%Stop recording.
                    [app.audioSignal]=getaudiodata(app.breathRecorder,'double');
                    app.Fs=app.breathRecorder.SampleRate;
                    app.time = 0:(1/app.Fs):(app.breathRecorder.TotalSamples/app.Fs);
                    app.time = app.time(1:end-1);
                    plot(app.UIAxes,app.time,app.audioSignal);
                    
                    weightFilt = weightingFilter( 'A-weighting',2000);
                    app.audioOut = weightFilt(app.audioSignal);
                    app.audioOut  = weightFilt(app.audioOut );
                   % app.audioOut  = weightFilt(app.audioOut );
                    
                    
                    %sound(app.audioSignal, 44100);
                    filename2 = 'C:\Users\ekshh\OneDrive\Music\file.wav';
                                        filename = 'C:\Users\ekshh\OneDrive\Music\fileFilter.wav';

                    audiowrite(filename, app.audioOut , 44100);
                    audiowrite(filename2, app.audioSignal, 44100);
                    
                    
                    sound(app.audioOut , 44100);
                    
                    plot(app.UIAxes2,app.time,app.audioOut );
                    BreathingRate(app);
                    
                case 'On'
                    cla(app.UIAxes3);
                    record(app.breathRecorder);%Start recording.


            end
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            cla(app.UIAxes3);
            [filename,PathName] = uigetfile({'*.wav';'*.mp3';'*.au';'*.m4a';'*.*'},'File Selector');
            info = audioinfo([PathName,filename]);
            disp(info.NumChannels);
            if info.NumChannels==2
                [app.audioSignal,app.Fs]=audioread([PathName,filename]);
                app.audioSignal=app.audioSignal(:,1);
                app.time = 0:(1/app.Fs):(info.TotalSamples/app.Fs)-(1/app.Fs);

            else
                [app.audioSignal,app.Fs]=audioread([PathName,filename]);
                app.time = 0:(1/app.Fs):(info.TotalSamples/app.Fs);
                app.time = app.time(1:end-1);
            end
            plot(app.UIAxes,app.time,app.audioSignal);
            weightFilt = weightingFilter( 'C-weighting',1000);
            app.audioOut  = weightFilt(app.audioSignal);
            app.audioOut  = weightFilt(app.audioOut );
            %app.audioOut  = weightFilt(app.audioOut );
            filename = 'C:\Users\ekshh\OneDrive\Music\fileFilter.wav';
            audiowrite(filename, app.audioOut, 44100);
            %sound(app.audioSignal, 44100);
            sound(app.audioOut , 44100);            
            plot(app.UIAxes2,app.time,app.audioOut );
            BreathingRate(app);

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Audio Row Data')
            xlabel(app.UIAxes, 'Time(sec)')
            ylabel(app.UIAxes, 'Audio Signal')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Open File';
            app.LabelSwitch = uilabel(app.UIFigure);
            app.LabelSwitch.Text = 'Record';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @RecordSwitchValueChanged, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'C-weighting')
            xlabel(app.UIAxes2, 'Time (sec)')
            ylabel(app.UIAxes2, 'Amplitude')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BreathRate

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end